classdef fingrid_api_app_auth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DashboardTab                    matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        EmissionFactorgCO2kWhEditFieldProduction  matlab.ui.control.NumericEditField
        EmissionFactorgCO2kWhEditField_2Label  matlab.ui.control.Label
        EmissionFactorgCO2kWhEditFieldConsumption  matlab.ui.control.NumericEditField
        EmissionFactorgCO2kWhLabel      matlab.ui.control.Label
        PriceEditField                  matlab.ui.control.NumericEditField
        MWhEditFieldLabel               matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        ConsumptionMWEditField          matlab.ui.control.NumericEditField
        ConsumptionMWEditFieldLabel     matlab.ui.control.Label
        ProductionMWEditField           matlab.ui.control.NumericEditField
        ProductionMWEditFieldLabel      matlab.ui.control.Label
        maxEditFieldConsumption         matlab.ui.control.NumericEditField
        maxEditField_4Label             matlab.ui.control.Label
        minEditFieldConsumption         matlab.ui.control.NumericEditField
        minEditField_5Label             matlab.ui.control.Label
        avgEditFieldConsumption         matlab.ui.control.NumericEditField
        avgEditField_4Label             matlab.ui.control.Label
        maxEditFieldProduction          matlab.ui.control.NumericEditField
        maxEditField_3Label             matlab.ui.control.Label
        minEditFieldProduction          matlab.ui.control.NumericEditField
        minEditField_3Label             matlab.ui.control.Label
        avgEditFieldProduction          matlab.ui.control.NumericEditField
        avgEditField_3Label             matlab.ui.control.Label
        maxEditFieldFreq                matlab.ui.control.NumericEditField
        maxEditField_2Label             matlab.ui.control.Label
        minEditFieldFreq                matlab.ui.control.NumericEditField
        minEditField_4Label             matlab.ui.control.Label
        avgEditFieldFreq                matlab.ui.control.NumericEditField
        avgEditField_2Label             matlab.ui.control.Label
        ElectricSystemFrequencyHzGauge  matlab.ui.control.SemicircularGauge
        AvgFrequencyHzLabel             matlab.ui.control.Label
        LampESS                         matlab.ui.control.Lamp
        Lamp_2Label                     matlab.ui.control.Label
        LampPSS                         matlab.ui.control.Lamp
        LampLabel                       matlab.ui.control.Label
        DateDropDownDashboard           matlab.ui.control.DropDown
        DateDropDown_2Label             matlab.ui.control.Label
        ElectricityPriceLabel           matlab.ui.control.Label
        ElectricityShortageStatusLabel  matlab.ui.control.Label
        PowerSystemStateLabel           matlab.ui.control.Label
        CurrentDataTimestampLabel       matlab.ui.control.Label
        FrequencyHzLabel                matlab.ui.control.Label
        ProductionMWLabel               matlab.ui.control.Label
        ConsumptionMWLabel              matlab.ui.control.Label
        LabelExportDashboard            matlab.ui.control.Label
        ExportButtonDashboard           matlab.ui.control.Button
        GetDataButtonDashboard          matlab.ui.control.Button
        UIAxesDashboard                 matlab.ui.control.UIAxes
        SearchTab                       matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        DaterangeisonemonthfromtheselecteddateLabel  matlab.ui.control.Label
        DatePicker                      matlab.ui.control.DatePicker
        DatePickerLabel                 matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        varEditField                    matlab.ui.control.NumericEditField
        varEditFieldLabel               matlab.ui.control.Label
        stdEditField                    matlab.ui.control.NumericEditField
        stdEditFieldLabel               matlab.ui.control.Label
        medEditField                    matlab.ui.control.NumericEditField
        medEditFieldLabel               matlab.ui.control.Label
        avgEditField                    matlab.ui.control.NumericEditField
        avgEditFieldLabel               matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        unitsEditField                  matlab.ui.control.EditField
        unitsEditFieldLabel             matlab.ui.control.Label
        DateDropDown                    matlab.ui.control.DropDown
        DateDropDownLabel               matlab.ui.control.Label
        SelectDropDown                  matlab.ui.control.DropDown
        SelectDropDownLabel             matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        GetDataButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ImportExportTab                 matlab.ui.container.Tab
        MinEditFieldIE                  matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        MaxEditFieldIE                  matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        AvgEditFieldIE                  matlab.ui.control.NumericEditField
        AvgEditFieldLabel               matlab.ui.control.Label
        LabelExportIE                   matlab.ui.control.Label
        ExportButtonIE                  matlab.ui.control.Button
        GetDataButtonIE                 matlab.ui.control.Button
        DateDropDownIE                  matlab.ui.control.DropDown
        DateDropDown_2Label_2           matlab.ui.control.Label
        HTML                            matlab.ui.control.HTML
        UIAxesIE                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % global db vars
        index
        name
        id
        unit
        hyperlink
        % the selected dataset
        dataset 
        % date selection
        dateSelect
        today
        startOfToday
        % export
        exportSearch
        exportDashboard
        exportIE
        % 
        powerTransferData
        %
        APIKeyFingrid 
        APIKeyEntsoE 
    end
    
    methods (Access = private)
       
        function [startTimeStr, endTimeStr] = generateDateStrings(~, timePeriod)
            % Get current date and time
            currentDate = datetime('now', 'TimeZone', 'UTC');
            
            % Initialize start and end times
            startTime = currentDate;
            endTime = currentDate;
            
            % Determine the day of the week (1 = Sunday, 2 = Monday, ..., 7 = Saturday)
            dayOfWeek = weekday(currentDate);
            
            % Calculate the offset to the most recent Monday
            offsetToMonday = mod(dayOfWeek - 2, 7);
            
            % Determine start and end times based on selected time period
            switch timePeriod
                case 'Current Week'
                    % Find the most recent Monday at 00:00:00
                    startTime = currentDate - days(offsetToMonday);
                    startTime.Hour = 0;
                    startTime.Minute = 0;
                    startTime.Second = 0;
                    % End time is the current date and time
                    endTime = currentDate;
                case 'Last Week'
                    % Find the Monday of the previous week at 00:00:00
                    startTime = (currentDate - days(offsetToMonday)) - days(7);
                    startTime.Hour = 0;
                    startTime.Minute = 0;
                    startTime.Second = 0;
                    % Set end time to the Sunday of the previous week at 23:59:59
                    endTime = startTime + days(6);
                    endTime.Hour = 23;
                    endTime.Minute = 59;
                    endTime.Second = 59;
                case 'Current Month'
                    startTime = dateshift(currentDate, 'start', 'month');
                    endTime = dateshift(currentDate, 'end', 'month');
                case 'Last Month'
                    startTime = dateshift(currentDate, 'start', 'month') - calmonths(1);
                    endTime = dateshift(startTime, 'end', 'month');
                otherwise
                    error('Invalid time period selected');
            end
            
            % Format the start and end times as strings
            startTimeStr = datestr(startTime, 'yyyy-mm-dd HH:MM:SS');
            endTimeStr = datestr(endTime, 'yyyy-mm-dd HH:MM:SS');
        end
        
        function [color,state] = pssLamp(~, avg_pss)
            switch avg_pss
                case 1                
                    color = 'g';
                    state = 'Normal';
                case 2
                    color = 'y';
                    state = 'Endangered';
                case 3
                    color = 'r';
                    state = 'Disturbed';
                case 4
                    color = 'k';
                    state = 'Blackout';
                case 5
                    color = 'b';
                    state = 'Restoration';
                otherwise
                    color = 'g';
                    state= 'Normal';
            end
        end
        
        function [color, state] = essLamp(~, avg_ess)
            switch avg_ess
                case 0
                    color = 'g';
                    state = 'Normal';
                case 1
                    color = 'y';
                    state = 'Shortage Possible';
                case 2
                    color = 'r';
                    state = 'High-risk of Shortage';
                case 3
                    color = 'k';
                    state = 'Shortage';
                otherwise
                    color = 'g';
                    state = 'Normal';
            end
            
        end
        
        % generate date range for the date picker
        function [startStr, endStr] = generateDateRange(~, startDate)
            % Convert the startDate to datetime if it's not already
            if ~isa(startDate, 'datetime')
                startDate = datetime(startDate);
            end
            
            % Calculate the end date (one month from the start date)
            endDate = startDate + calmonths(1);
            
            % Format dates as strings in the required format for the API
            startStr = datestr(startDate, 'yyyy-mm-ddTHH:MM:ss.000Z');
            endStr = datestr(endDate, 'yyyy-mm-ddTHH:MM:ss.000Z');
        end
        
        function exportDataToCSV(~, data, label, filename)
            if isempty(data)
                label.Text = "No data.";
            else
                % Get variable names from the table
                varNames = data.Properties.VariableNames;
                
                % Find the index of the Timestamp column
                timestampIdx = find(strcmp(varNames, 'Timestamp'));
                
                % Convert datetime to string for CSV export
                timestampStrings = cellstr(datestr(data.Timestamp, 'yyyy-mm-dd HH:MM:SS'));
                
                % Create headers (reorder to put Timestamp first if it's not already)
                headers = [varNames(timestampIdx), varNames(setdiff(1:end, timestampIdx))];
                
                % Initialize the exportData cell array
                exportData = cell(height(data) + 1, width(data));
                exportData(1,:) = headers;
                
                % Add timestamp data
                exportData(2:end, 1) = timestampStrings;
                
                % Add value data for each variable
                for i = 1:width(data)
                    if i ~= timestampIdx
                        colData = data.(varNames{i});
                        if isnumeric(colData)
                            exportData(2:end, i) = num2cell(colData);
                        else
                            exportData(2:end, i) = table2cell(data(:,varNames{i}));
                        end
                    end
                end
                
                % Generate a unique file name
                timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                fileName = sprintf('%s_%s.csv', filename, timestamp);
                
                % Check if file exists and increment a counter if necessary
                counter = 1;
                while isfile(fileName)
                    fileName = sprintf('%s_%s_%d.csv', filename, timestamp, counter);
                    counter = counter + 1;
                end
                
                % Write to CSV
                [file, path] = uiputfile(fileName, 'Save CSV File');
                if file ~= 0  % User didn't cancel
                    fullPath = fullfile(path, file);
                    writecell(exportData, fullPath);
                    label.Text = sprintf("Data exported to: %s", fullPath);
                    label.FontColor = 'g';
                else
                    label.Text = "Export cancelled.";
                    label.FontColor = 'r';
                end
            end
            
        end
        
        function current_price = fetch_current_price(~, apiKey)
            % Get the current time in UTC
            % TimeZone EET
            current_time = datetime('now', 'TimeZone', 'Europe/Helsinki');
            
            % Determine if current time is between 22:00 and 23:59
            % if hour(current_time) >= 22
            %     % If it is between 22:00 and 23:59, use the interval from 22:00 today to 22:00 tomorrow
            %     start_time = dateshift(current_time, 'start', 'day') + hours(22);
            %     end_time = start_time + days(1);
            % else
            %     % Otherwise, use the interval from 22:00 the previous day to 22:00 today
            %     end_time = dateshift(current_time, 'start', 'day') + hours(22);
            %     start_time = end_time - days(1);
            % end

            % Define start and end times
            start_time = current_time;       % Start time is the current time
            end_time = start_time + days(1); % End time is 1 day later

            % Convert times to the required string format
            start_str = datestr(start_time, 'yyyy-mm-ddTHH:MM:SSZ');
            end_str = datestr(end_time, 'yyyy-mm-ddTHH:MM:SSZ');
            
            % Define the API URL with the computed TimeInterval
            api_url = "https://web-api.tp.entsoe.eu/api?documentType=A44&out_Domain=10YFI-1--------U" + ...
                "&in_Domain=10YFI-1--------U&TimeInterval=" + start_str + "/" + end_str + "&securityToken=" ...
                + apiKey;
            
            % Read the XML data as a string
            xml_string = webread(api_url);
            
            % Display XML for debugging
            % disp('Received XML:');
            % disp(xml_string);
            
            % Check if the XML contains a reason for no data
            if contains(xml_string, 'No matching data found')
                disp('No data found for the requested parameters.');
                current_price = NaN;
                return;
            end
            
            % Calculate the current position based on the current hour
            current_hour = hour(current_time);
            % position = current_hour or position = current_hour + 1 ?
            position = current_hour;
            
            % Create a regex pattern to find the price for the specific position
            pattern = sprintf('<Point>\\s*<position>%d</position>\\s*<price.amount>([^<]+)</price.amount>\\s*</Point>', position);
            
            % Use regex to find the price amount for the current position
            price_match = regexp(xml_string, pattern, 'tokens', 'once');
            
            if ~isempty(price_match)
                % Extract the price amount from the match
                current_price = str2double(price_match{1});
                % fprintf('The current price is: %.2f\n', current_price);
            else
                % disp('Current price not found.');
                current_price = NaN;
            end
        end

        function promptForAPIKeys(app)
            % Create the dialog box
            d = dialog('Position', [300, 300, 350, 200], 'Name', 'Enter API Keys');

            % Create and configure the text fields and labels
            uicontrol('Parent', d, 'Style', 'text', 'Position', [20, 140, 100, 30], 'String', 'Fingrid API Key:');
            fingridEditField = uicontrol('Parent', d, 'Style', 'edit', 'Position', [120, 145, 200, 25]);

            uicontrol('Parent', d, 'Style', 'text', 'Position', [20, 80, 100, 30], 'String', 'Entso-E Token:');
            entsoeEditField = uicontrol('Parent', d, 'Style', 'edit', 'Position', [120, 85, 200, 25]);

            % Create the OK button and configure its callback
            uicontrol('Parent', d, 'Position', [140, 20, 70, 25], 'String', 'OK', 'Callback', @(src, event) okButtonCallback(app, d, fingridEditField, entsoeEditField, app));
        end

        function okButtonCallback(~, dialog, fingridEditField, entsoeEditField, app)
            % Get the values from the text fields
            app.APIKeyFingrid = fingridEditField.String;
            app.APIKeyEntsoE = entsoeEditField.String;

            % Close the dialog
            delete(dialog);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            promptForAPIKeys(app);
    
            % Wait for the user to enter the API keys
            waitfor(findall(0, 'Type', 'figure', 'Name', 'Enter API Keys'));
            
            % Check if the API keys are entered, otherwise, show an error
            if isempty(app.APIKeyFingrid) || isempty(app.APIKeyEntsoE)
                uialert(app.UIFigure, 'API keys are required to use this app.', 'Missing API Keys');
                return;
            end
            
            % load data keys and indexes
            app.index = load("index.mat");
            app.name = load("name.mat");
            app.id = load("id.mat");
            app.unit = load("unit.mat");
            app.hyperlink = load("hyperlink.mat");
            % set date picker to today
            app.today = datetime('now', 'TimeZone', 'UTC');
            app.startOfToday = dateshift(app.today,'start','day');
            app.DatePicker.Value = app.startOfToday;
            % initialize the export tables
            app.exportSearch = table('Size', [0, 2], ...
                             'VariableTypes', {'datetime', 'double'}, ...
                             'VariableNames', {'Timestamp', 'Value'});
            app.exportDashboard = table('Size', [0, 3], ...
                             'VariableTypes', {'datetime', 'double','double'}, ...
                             'VariableNames', {'Timestamp', 'Consumption','Production'});
            app.exportIE = table('Size', [0, 2], ...
                             'VariableTypes', {'datetime', 'double'}, ...
                             'VariableNames', {'Timestamp', 'Value'});
            % axes bg
            % app.UIAxesDashboard.Color = "#383d46";
            % app.UIAxes.Color = "#383d46";
            % app.UIAxesIE.Color = "#383d46";
            app.DatePicker.Limits = [datetime([2015 1 1], 'TimeZone','UTC') datetime('now', 'TimeZone', 'UTC')];
        end

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)
            % progress bar
            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Fetching data ...',...
                'Indeterminate','on');
            drawnow

            % set the export label to empty
            app.Label.Text = "";
            
            app.dataset = app.SelectDropDown.Value;
            dataset_idx = find(strcmp(app.name.name, app.dataset));

            if strcmp(app.DateDropDown.Value, "---") 
                [startStr, endStr] = generateDateRange(app, app.DatePicker.Value);
            else
                [startStr, endStr] = generateDateStrings(app, app.DateDropDown.Value);
            end

            api_url = "https://data.fingrid.fi/api/datasets/" + num2str(app.id.id(dataset_idx)) + "/data?startTime=" + startStr + "&endTime=" + endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" + app.APIKeyFingrid;            
            api_call = webread(api_url);

            % Pause for 2 seconds so the API can accept a new request.
            pause(2);
            dataset_api_url = "https://data.fingrid.fi/api/datasets/" + num2str(app.id.id(dataset_idx)) + "?x-api-key=" + app.APIKeyFingrid;
            dataset_api_call = webread(dataset_api_url);

            % add description
            app.DescriptionTextArea.Value = dataset_api_call.descriptionEn;

            % Extract all startTime values into a cell array
            startTimes = {api_call.data.startTime};

            % Convert timestamp strings to datetime objects
            datetimes = datetime(startTimes, 'InputFormat', 'yyyy-MM-dd''T''HH:mm:ss.SSS''Z''', 'TimeZone', 'UTC');

            % Extract and convert values to a numeric array
            values = [api_call.data.value];

            % Create a table with datetimes and values
            app.exportSearch = table(datetimes', values', 'VariableNames', {'Timestamp', 'Value'});

            min_val = min(values);
            max_val = max(values);
            avg_val = mean(values);
            med_val = median(values);
            var_val = var(values);
            std_val = std(values);

            app.unitsEditField.Value = app.unit.unit{dataset_idx};
            app.maxEditField.Value = max_val;
            app.minEditField.Value = min_val;
            app.avgEditField.Value = avg_val;
            app.medEditField.Value = med_val;
            app.varEditField.Value = var_val;
            app.stdEditField.Value = std_val;

            % Get the current axis
            ax = app.UIAxes;

            % Create the plot
            plot(ax, datetimes, values);
            hold(ax, 'on');
            % plot the avg line
            plot(ax, datetimes, avg_val*ones(size(datetimes)), '-r');
            % plot the std lines
            plot(ax, datetimes, (avg_val + std_val)*ones(size(datetimes)), '--r');
            plot(ax, datetimes, (avg_val - std_val)*ones(size(datetimes)), '--r');

            % Add labels and title as needed
            ax.XLabel.String = "Time";
            ax.YLabel.String = app.unit.unit{dataset_idx};
            ax.Title.String = app.SelectDropDown.Value;
            ax.Color = 'white';
            hold(ax,'off');

            % close progress bar
            close(d)
        end

        % Button pushed function: GetDataButtonDashboard
        function GetDataButtonDashboardPushed(app, event)
            % progress bar
            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Fetching data ...',...
                'Indeterminate','on');
            drawnow

            % set the export label to empty
            app.LabelExportDashboard.Text = "";

            dataset_idx = find(app.id.id==192);
            % start and end date
            [startStr, endStr] = generateDateStrings(app, app.DateDropDownDashboard.Value);
            % consumption id = 193
            api_url_consumption = "https://data.fingrid.fi/api/datasets/193/data?startTime=" + startStr + "&endTime=" + endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" + app.APIKeyFingrid;
            api_call_consumption = webread(api_url_consumption);

            start_times_consumption = {api_call_consumption.data.startTime};
            % Add timestamp label
            currentDate = datetime('now', 'TimeZone', 'UTC');
            app.CurrentDataTimestampLabel.Text = "Current Data - Timestamp: " + string(currentDate) + " UTC";
            % Convert timestamp strings to datetime objects
            datetimes_consumption = datetime(start_times_consumption, 'InputFormat', 'yyyy-MM-dd''T''HH:mm:ss.SSS''Z''', 'TimeZone', 'UTC');
            % Extract and convert values to a numeric array
            values_consumption = [api_call_consumption.data.value];

            % avg consumption values
            avg_consumption = mean(values_consumption);
            min_consumption = min(values_consumption);
            max_consumption = max(values_consumption);
            app.ConsumptionMWEditField.Value = values_consumption(end);

            pause(2)
            % production id = 192
            api_url_production = "https://data.fingrid.fi/api/datasets/192/data?startTime=" + startStr + "&endTime=" + endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" + app.APIKeyFingrid;
            api_call_production = webread(api_url_production);

            start_times_production = {api_call_production.data.startTime};
            % Convert timestamp strings to datetime objects
            datetimes_production = datetime(start_times_production, 'InputFormat', 'yyyy-MM-dd''T''HH:mm:ss.SSS''Z''', 'TimeZone', 'UTC');
            % Extract and convert values to a numeric array
            values_production = [api_call_production.data.value];

            % avg production values
            avg_production = mean(values_production);
            min_production = min(values_production);
            max_production = max(values_production);
            app.ProductionMWEditField.Value = values_production(end);

            % Create a table with datetimes and values
            app.exportDashboard = table(datetimes_production', values_consumption', values_production', ...
                'VariableNames', {'Timestamp', 'Consumption', 'Production'});

            % Get the current axis
            ax = app.UIAxesDashboard;

            % Create the plots
            % consumption
            plot(ax, datetimes_consumption, values_consumption);
            hold(ax, 'on');
            % production
            plot(ax, datetimes_production, values_production, '-r');
            % plot the consumption avg line
            plot(ax, datetimes_consumption, avg_consumption*ones(size(datetimes_consumption)), '--b');
            % plot the production avg line
            plot(ax, datetimes_production, avg_production*ones(size(datetimes_production)), '--r');
            legend(ax, 'Consumption','Production','Consumption Avg', 'Production Avg');

            % Add labels and title
            ax.XLabel.String = "Time";
            ax.YLabel.String = app.unit.unit{dataset_idx};
            % ax.Title.String = app.SelectDropDown.Value;
            ax.Title.String = 'Electricity production and consumption - real time data (3min)';
            ax.Title.Color = '#d3ddef';
            ax.Color = 'white';
            ax.Legend.Color = 'white';
            hold(ax,'off');

            % key values in time range
            % consumption
            app.avgEditFieldConsumption.Value = avg_consumption;
            app.minEditFieldConsumption.Value = min_consumption;
            app.maxEditFieldConsumption.Value = max_consumption;
            % production
            app.avgEditFieldProduction.Value = avg_production;
            app.minEditFieldProduction.Value = min_production;
            app.maxEditFieldProduction.Value = max_production;

            pause(2)
            % emission factor - consumption id 265
            api_url_emission_consumption = "https://data.fingrid.fi/api/datasets/265/data?startTime=" + startStr+ "&endTime=" ...
                + endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" ...
                + app.APIKeyFingrid;
            api_call_emission_consumption = webread(api_url_emission_consumption);
            values_emission_consumption = [api_call_emission_consumption.data.value];
            app.EmissionFactorgCO2kWhEditFieldConsumption.Value = values_emission_consumption(end);

            pause(2)
            % emission factor - production id 266
            api_url_emission_production = "https://data.fingrid.fi/api/datasets/266/data?startTime=" + startStr+ "&endTime=" ...
                + endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" ...
                + app.APIKeyFingrid;
            api_call_emission_production = webread(api_url_emission_production);
            values_emission_production = [api_call_emission_production.data.value];
            app.EmissionFactorgCO2kWhEditFieldProduction.Value = values_emission_production(end);
            

            pause(2)
            api_url_pss = "https://data.fingrid.fi/api/datasets/209/data?startTime=" + startStr + "&endTime=" + endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" + app.APIKeyFingrid;
            api_call_pss = webread(api_url_pss);
            values_pss = [api_call_pss.data.value];

            avg_pss = ceil(mean(values_pss));
            [pss_color, pss_state] = pssLamp(app, avg_pss);
            app.LampPSS.Color = pss_color;
            app.LampLabel.Text = pss_state;

            pause(2)
            api_url_ess = "https://data.fingrid.fi/api/datasets/336/data?startTime=" + startStr + "&endTime=" + endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" + app.APIKeyFingrid;
            api_call_ess = webread(api_url_ess);
            values_ess = [api_call_ess.data.value];

            avg_ess = ceil(mean(values_ess));
            [ess_color, ess_state] = essLamp(app, avg_ess);
            app.LampESS.Color = ess_color;
            app.Lamp_2Label.Text = ess_state;

            pause(2)
            % frequency id = 177
            api_url_frequency = "https://data.fingrid.fi/api/datasets/177/data?startTime=" + startStr + "&endTime=" + endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" + app.APIKeyFingrid;
            api_call_frequency = webread(api_url_frequency);

            values_frequency = [api_call_frequency.data.value];
            min_frequency = min(values_frequency);
            max_frequency = max(values_frequency);
            med_frequency = median(values_frequency);
            avg_frequency = mean(values_frequency);

            app.ElectricSystemFrequencyHzGauge.Value = values_frequency(end);
            app.FrequencyHzEditField.Value = values_frequency(end);
            app.minEditFieldFreq.Value = min_frequency;
            app.maxEditFieldFreq.Value = max_frequency;
            app.avgEditFieldFreq.Value = avg_frequency;

            % get price
            current_price = fetch_current_price(app, app.APIKeyEntsoE);
            app.PriceEditField.Value = current_price;

            % close progress bar
            close(d)
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
            app.DateDropDown.Value = "---";
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            exportDataToCSV(app, app.exportSearch, app.Label, "search_data");
        end

        % Button pushed function: ExportButtonDashboard
        function ExportButtonDashboardPushed(app, event)
            exportDataToCSV(app, app.exportDashboard, app.LabelExportDashboard, "dashboard_data");
        end

        % Button pushed function: GetDataButtonIE
        function GetDataButtonIEPushed(app, event)
            % progress bar
            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Fetching data ...',...
                'Indeterminate','on');
            drawnow
            
            % empty the export label
            app.LabelExportIE.Text = "";

            % today
            currentDate = datetime('now', 'TimeZone', 'UTC');
            startDate = dateshift(app.today,'start','day');
            startStr = datestr(startDate, 'yyyy-mm-ddTHH:MM:SS');
            endStr = datestr(currentDate, 'yyyy-mm-ddTHH:MM:SS');

             % Central Sweden id = 89
            api_url_central_sweden = "https://data.fingrid.fi/api/datasets/89/data?startTime=" + startStr + "&endTime=" +  endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" + app.APIKeyFingrid;
            api_call_central_sweden = webread(api_url_central_sweden);
            pause(2)
            % Estonia id = 180
            api_url_estonia= "https://data.fingrid.fi/api/datasets/180/data?startTime=" + startStr + "&endTime=" +  endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" + app.APIKeyFingrid;
            api_call_estonia = webread(api_url_estonia);
            pause(2);
            % Northern Sweden id = 87
            api_url_northern_sweden = "https://data.fingrid.fi/api/datasets/87/data?startTime=" + startStr + "&endTime=" +  endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" + app.APIKeyFingrid;
            api_call_northern_sweden = webread(api_url_northern_sweden);
            pause(2);
            % Norway id = 187
            api_url_norway = "https://data.fingrid.fi/api/datasets/187/data?startTime=" + startStr + "&endTime=" +  endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" + app.APIKeyFingrid;
            api_call_norway = webread(api_url_norway);
            pause(2);
            % Åland id = 90
            api_url_oland = "https://data.fingrid.fi/api/datasets/90/data?startTime=" + startStr + "&endTime=" +  endStr + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" + app.APIKeyFingrid;
            api_call_oland = webread(api_url_oland);

            % import and export rates for today
            rates_central_sweden =  [api_call_central_sweden.data.value];
            rates_estonia =  [api_call_estonia.data.value];
            rates_northern_sweden =  [api_call_northern_sweden.data.value];
            rates_norway =  [api_call_norway.data.value];
            rates_oland =  [api_call_oland.data.value];

            app.powerTransferData = struct();
            app.powerTransferData.central_sweden = rates_central_sweden(end);
            app.powerTransferData.estonia = rates_estonia(end);
            app.powerTransferData.northern_sweden = rates_northern_sweden(end);
            app.powerTransferData.norway = rates_norway(end);
            app.powerTransferData.oland = rates_oland(end);
            app.powerTransferData.timestamp = endStr;
                
            rates = [rates_central_sweden(end),rates_estonia(end),rates_northern_sweden(end),rates_norway(end),rates_oland(end)];
            app.powerTransferData.max_rate = max(rates);
            app.powerTransferData.min_rate = min(rates);
            app.powerTransferData.avg_rate = round(mean(rates));
            app.powerTransferData.med_rate = round(median(rates));

            pause(2);
            % net import-export id = 194
            [startStrIE, endStrIE] = generateDateStrings(app, app.DateDropDownIE.Value);
            api_url_net_ie = "https://data.fingrid.fi/api/datasets/194/data?startTime=" + startStrIE + "&endTime=" + endStrIE + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" + app.APIKeyFingrid;
            api_call_net_ie = webread(api_url_net_ie);
            % 
            start_times_net_ie = {api_call_net_ie.data.startTime};
            % % Convert timestamp strings to datetime objects
            datetimes_net_ie = datetime(start_times_net_ie, 'InputFormat', 'yyyy-MM-dd''T''HH:mm:ss.SSS''Z''', 'TimeZone', 'UTC');
            % % Extract and convert values to a numeric array
            values_net_ie = [api_call_net_ie.data.value];

            pause(2)
            % current net IE rate
            [startStrIECurrent, endStrIECurrent] = generateDateStrings(app, "Current Week");
            api_url_net_ie_current = "https://data.fingrid.fi/api/datasets/194/data?startTime=" + startStrIECurrent + "&endTime=" ...
                + endStrIECurrent + "&format=json&&pageSize=20000&locale=en&sortBy=startTime&sortOrder=asc&x-api-key=" ...
                + app.APIKeyFingrid;
            api_call_net_ie_current = webread(api_url_net_ie_current);
            values_net_ie_current = [api_call_net_ie_current.data.value];
            app.powerTransferData.net_ie = values_net_ie_current(end);

            % Create a table with datetimes and values
            app.exportIE = table(datetimes_net_ie', values_net_ie', 'VariableNames', {'Timestamp', 'Value'});

            avg_values_net_ie = mean(values_net_ie);
            std_values_net_ie = std(values_net_ie);

            app.AvgEditFieldIE.Value = avg_values_net_ie;
            app.MinEditFieldIE.Value = min(values_net_ie);
            app.MaxEditFieldIE.Value = max(values_net_ie);

            % 
            dataset_idx = find(app.id.id==194);
            ax = app.UIAxesIE;
            plot(ax, datetimes_net_ie, values_net_ie);
            hold(ax, 'on');
            % plot the avg line
            plot(ax, datetimes_net_ie, avg_values_net_ie*ones(size(datetimes_net_ie)), '-r');
            % plot the std lines
            plot(ax, datetimes_net_ie, (avg_values_net_ie + std_values_net_ie)*ones(size(datetimes_net_ie)), '--r');
            plot(ax, datetimes_net_ie, (avg_values_net_ie - std_values_net_ie)*ones(size(datetimes_net_ie)), '--r');

            ax.XLabel.String = "Time";
            ax.YLabel.String = app.unit.unit{dataset_idx};
            ax.Title.String = app.name.name(dataset_idx) + " - " + app.DateDropDownIE.Value;
            ax.Title.Color = 'white';
            ax.Color = 'white';

            hold(ax,'off');

            % send data to html
            jsonData = jsonencode(app.powerTransferData);
            disp('Sending data to HTML:');
            disp(jsonData);
            app.HTML.sendEventToHTMLSource('updatePowerData', jsonData);

            % close progress bar
            close(d)
        end

        % Button pushed function: ExportButtonIE
        function ExportButtonIEPushed(app, event)
            exportDataToCSV(app, app.exportIE, app.LabelExportIE, "import_export");
        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            app.DatePicker.Value = app.startOfToday;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DashboardTab = uitab(app.TabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.UIAxesDashboard = uiaxes(app.DashboardTab);
            xlabel(app.UIAxesDashboard, 'Time')
            ylabel(app.UIAxesDashboard, 'Unit')
            zlabel(app.UIAxesDashboard, 'Z')
            app.GetDataButtonDashboard = uibutton(app.DashboardTab, 'push');
            app.GetDataButtonDashboard.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonDashboardPushed, true);
            app.GetDataButtonDashboard.Text = 'Get Data';
            app.ExportButtonDashboard = uibutton(app.DashboardTab, 'push');
            app.ExportButtonDashboard.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonDashboardPushed, true);
            app.ExportButtonDashboard.Text = 'Export';
            app.LabelExportDashboard = uilabel(app.DashboardTab);
            app.LabelExportDashboard.Text = '';
            app.ConsumptionMWLabel = uilabel(app.DashboardTab);
            app.ConsumptionMWLabel.Text = 'Consumption [MW]';
            app.ProductionMWLabel = uilabel(app.DashboardTab);
            app.ProductionMWLabel.Text = 'Production [MW]';
            app.FrequencyHzLabel = uilabel(app.DashboardTab);
            app.FrequencyHzLabel.Text = 'Frequency [Hz]';
            app.CurrentDataTimestampLabel = uilabel(app.DashboardTab);
            app.CurrentDataTimestampLabel.Text = 'Current Data - Timestamp:';
            app.PowerSystemStateLabel = uilabel(app.DashboardTab);
            app.PowerSystemStateLabel.Text = 'Power System State';
            app.ElectricityShortageStatusLabel = uilabel(app.DashboardTab);
            app.ElectricityShortageStatusLabel.Text = 'Electricity Shortage Status';
            app.ElectricityPriceLabel = uilabel(app.DashboardTab);
            app.ElectricityPriceLabel.Text = 'Electricity Price';
            app.DateDropDown_2Label = uilabel(app.DashboardTab);
            app.DateDropDown_2Label.Text = 'Date';
            app.DateDropDownDashboard = uidropdown(app.DashboardTab);
            app.DateDropDownDashboard.Items = {'Current Week', 'Last Week', 'Current Month', 'Last Month'};
            app.DateDropDownDashboard.Value = 'Current Week';
            app.LampLabel = uilabel(app.DashboardTab);
            app.LampLabel.Text = '--';
            app.LampPSS = uilamp(app.DashboardTab);
            app.Lamp_2Label = uilabel(app.DashboardTab);
            app.Lamp_2Label.Text = '--';
            app.LampESS = uilamp(app.DashboardTab);
            app.AvgFrequencyHzLabel = uilabel(app.DashboardTab);
            app.AvgFrequencyHzLabel.Text = 'Electric System Frequency [Hz]';
            app.ElectricSystemFrequencyHzGauge = uigauge(app.DashboardTab, 'semicircular');
            app.ElectricSystemFrequencyHzGauge.Limits = [49 51];
            app.ElectricSystemFrequencyHzGauge.MajorTicks = [49 49.5 50 50.5 51];
            app.avgEditField_2Label = uilabel(app.DashboardTab);
            app.avgEditField_2Label.Text = 'avg';
            app.avgEditFieldFreq = uieditfield(app.DashboardTab, 'numeric');
            app.minEditField_4Label = uilabel(app.DashboardTab);
            app.minEditField_4Label.Text = 'min';
            app.minEditFieldFreq = uieditfield(app.DashboardTab, 'numeric');
            app.maxEditField_2Label = uilabel(app.DashboardTab);
            app.maxEditField_2Label.Text = 'max';
            app.maxEditFieldFreq = uieditfield(app.DashboardTab, 'numeric');
            app.avgEditField_3Label = uilabel(app.DashboardTab);
            app.avgEditField_3Label.Text = 'avg';
            app.avgEditFieldProduction = uieditfield(app.DashboardTab, 'numeric');
            app.minEditField_3Label = uilabel(app.DashboardTab);
            app.minEditField_3Label.Text = 'min';
            app.minEditFieldProduction = uieditfield(app.DashboardTab, 'numeric');
            app.maxEditField_3Label = uilabel(app.DashboardTab);
            app.maxEditField_3Label.Text = 'max';
            app.maxEditFieldProduction = uieditfield(app.DashboardTab, 'numeric');
            app.avgEditField_4Label = uilabel(app.DashboardTab);
            app.avgEditField_4Label.Text = 'avg';
            app.avgEditFieldConsumption = uieditfield(app.DashboardTab, 'numeric');
            app.minEditField_5Label = uilabel(app.DashboardTab);
            app.minEditField_5Label.Text = 'min';
            app.minEditFieldConsumption = uieditfield(app.DashboardTab, 'numeric');
            app.maxEditField_4Label = uilabel(app.DashboardTab);
            app.maxEditField_4Label.Text = 'max';
            app.maxEditFieldConsumption = uieditfield(app.DashboardTab, 'numeric');
            app.ProductionMWEditFieldLabel = uilabel(app.DashboardTab);
            app.ProductionMWEditFieldLabel.Text = 'Production [MW]';
            app.ProductionMWEditField = uieditfield(app.DashboardTab, 'numeric');
            app.ConsumptionMWEditFieldLabel = uilabel(app.DashboardTab);
            app.ConsumptionMWEditFieldLabel.Text = 'Consumption [MW]';
            app.ConsumptionMWEditField = uieditfield(app.DashboardTab, 'numeric');
            app.FrequencyHzEditFieldLabel = uilabel(app.DashboardTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency [Hz]';
            app.FrequencyHzEditField = uieditfield(app.DashboardTab, 'numeric');
            app.MWhEditFieldLabel = uilabel(app.DashboardTab);
            app.MWhEditFieldLabel.Text = '€/MWh';
            app.PriceEditField = uieditfield(app.DashboardTab, 'numeric');
            app.EmissionFactorgCO2kWhLabel = uilabel(app.DashboardTab);
            app.EmissionFactorgCO2kWhLabel.Text = {'Emission Factor'; '[gCO2/kWh]'};
            app.EmissionFactorgCO2kWhEditFieldConsumption = uieditfield(app.DashboardTab, 'numeric');
            app.EmissionFactorgCO2kWhEditField_2Label = uilabel(app.DashboardTab);
            app.EmissionFactorgCO2kWhEditField_2Label.Text = {'Emission Factor'; '[gCO2/kWh]'};
            app.EmissionFactorgCO2kWhEditFieldProduction = uieditfield(app.DashboardTab, 'numeric');
            app.Image = uiimage(app.DashboardTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'power_flow_logo.png');
            app.SearchTab = uitab(app.TabGroup);
            app.SearchTab.Title = 'Search';
            app.UIAxes = uiaxes(app.SearchTab);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Unit')
            zlabel(app.UIAxes, 'Z')
            app.GetDataButton = uibutton(app.SearchTab, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Text = 'Get Data';
            app.ExportButton = uibutton(app.SearchTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Label = uilabel(app.SearchTab);
            app.Label.Text = '';
            app.SelectDropDownLabel = uilabel(app.SearchTab);
            app.SelectDropDownLabel.Text = 'Select';
            app.SelectDropDown = uidropdown(app.SearchTab);
            app.SelectDropDown.Items = {'aFRR energy activation volume local selection down', 'aFRR energy activation volume local selection up', 'aFRR energy activation volume with marginal price down', 'aFRR energy activation volume with marginal price up', 'aFRR energy cross border marginal price down', 'aFRR energy cross border marginal price up', 'aFRR energy volume weighted average price down', 'aFRR energy volume weighted average price up', 'Automatic Frequency Restoration Reserve activated down (60min)', 'Automatic Frequency Restoration Reserve activated up (60min)', 'Automatic Frequency Restoration Reserve capacity down (60min)', 'Automatic Frequency Restoration Reserve capacity up (60min)', 'Automatic Frequency Restoration Reserve price down (60min)', 'Automatic Frequency Restoration Reserve price up (60min)', 'Balancing Capacity Market (mFRR) up hourly market price (60min)', 'Balancing Capacity (mFRR) down hourly market bids (60min)', 'Balancing Capacity (mFRR) down hourly market price (60min)', 'Balancing Capacity (mFRR) down hourly market procured volume (60min)', 'Balancing Capacity (mFRR) down hourly market procurement forecast (60min)', 'Balancing Capacity (mFRR) hourly market procurement from Estonia (60min)', 'Balancing Capacity (mFRR) up hourly market bids (60min)', 'Balancing Capacity (mFRR) up hourly market procurement forecast (60min)', 'Balancing Capacity (mFRR) up hourly market procured volume (60min)', 'Bilateral trade between FI-RUS (60min)', 'Bilateral trade capacity FI-RUS (60min)', 'Bilateral trade capacity RUS-FI (60min)', 'Bilateral trade capacity FI-RUS unused (60min)', 'Bilateral trade capacity RUS-FI unused (60min)', 'Cogeneration of district heating - real time data (3min)', 'Commercial transmission of electricity between FI-EE', 'Commercial transmission of electricity between FI-SE1', 'Commercial transmission of electricity between FI-SE3', 'Cross-border transmission fee export to Russia (60min)', 'Day-ahead transmission capacity EE-FI - official (60min)', 'Day-ahead transmission capacity FI-EE - official (60min)', 'Day-ahead transmission capacity FI-SE1 - official (60min)', 'Day-ahead transmission capacity FI-SE1 - planned (60min)', 'Day-ahead transmission capacity FI-SE3 - official (60min)', 'Day-ahead transmission capacity FI-SE3 - planned (60min)', 'Day-ahead transmission capacity SE1-FI - official (60min)', 'Day-ahead transmission capacity SE1-FI - planned (60min)', 'Day-ahead transmission capacity SE3-FI - official (60min)', 'Down-regulation bids, price of the last activated - real time data (60 min)', 'Day-ahead transmission capacity SE3-FI - planned (60min)', 'Electricity consumption forecast - next 24 hours (60min)', 'Electricity consumption forecast - updated hourly (60min)', 'Electricity consumption in Finland (60min)', 'Electricity consumption in Finland - real time data (3min)', 'Electricity production in Finland (60min)', 'Electricity production in Finland - real time data (3min)', 'Electricity production prediction - updated hourly (60min)', 'Electricity production prediction - premilinary (60min)', 'Electricity production reserve power plants and small-scale production - real time data (3min)', 'Electricity production surplus/deficit - real time data (3min)', 'Electricity shortage status (3min)', 'Emission factor for electricity consumed in Finland - real time data (3min)', 'Emission factor of electricity production in Finland - real time data (3min)', 'Fast Frequency Reserve FFR price (60min)', 'Fast Frequency Reserve FFR procurement forecast (60min)', 'Fast Frequency Reserve FFR procured volume (60min)', 'Fast Frequency Reserve FFR received bids (60min)', 'Frequency containment reserves for disturbances downwards regulation hourly market prices (60min)', 'Frequency containment reserves for disturbances downward regulation nordic trade (60min)', 'Frequency containment reserve for disturbances downwards regulation procured volumes in hourly market (60min)', 'Frequency containment reserve for disturbances downwards regulation received bids in hourly market (60min)', 'Frequency containment reserves for disturbances downward regulation reserve plans in the yearly market (60min)', 'Frequency containment reserves for disturbances upwards regulation hourly market prices (60min)', 'Frequency containment reserves for disturbances upwards regulation nordic trade (60min)', 'Frequency containment reserve for disturbances upwards regulation procured volumes in hourly market (60min)', 'Frequency containment reserve for disturbances upwards regulation received bids in hourly market (60min)', 'Frequency containment reserves for disturbances upwards regulation reserve plans in the yearly market (60min)', 'Frequency Containment Reserve for Normal operation activated (60min)', 'Frequency Containment Reserve for Normal operation foreign trade (60min)', 'Frequency Containment Reserve for Normal operation hourly market bids (60min)', 'Frequency Containment Reserve for Normal operation hourly market prices (60min)', 'Frequency Containment Reserve for Normal operation hourly market volumes (60min)', 'Frequency Containment Reserve for Normal operation yearly market plans (60min)', 'Frequency - real time data (3min)', 'Hour change regulation down-regulation (60min)', 'Hour change regulation up-regulation (60min)', 'Hydro power production - real time data (3min)', 'Imbalance price (60min)', 'Imbalance power between Finland and Sweden (60min)', 'Incentivising Component (IC) DK1 (60min)', 'Incentivising Component (IC) DK2 (60min)', 'Incentivising Component (IC) FI (60min)', 'Incentivising Component (IC) NO1 (60min)', 'Incentivising Component (IC) NO2 (60min)', 'Incentivising Component (IC) NO3 (60min)', 'Incentivising Component (IC) NO4 (60min)', 'Incentivising Component (IC) NO5 (60min)', 'Incentivising Component (IC) SE1 (60min)', 'Incentivising Component (IC) SE2 (60min)', 'Incentivising Component (IC) SE3 (60min)', 'Incentivising Component (IC) SE4 (60min)', 'Industrial cogeneration - real time data (3min)', 'Intraday transmission capacity EE-FI (60min)', 'Intraday transmission capacity EE-FI - real time data (60min)', 'Intraday transmission capacity FI-EE (60min)', 'Intraday transmission capacity FI-EE - real time data (60min)', 'Intraday transmission capacity FI-RUS (60min)', 'Intraday transmission capacity FI - SE1 (60min)', 'Intraday transmission capacity FI-SE3 (60min)', 'Intraday transmission capacity RUS-FI (60min)', 'Intraday transmission capacity SE1-FI (60min)', 'Intraday transmission capacity SE3-FI (60min)', 'Kinetic energy of the Nordic power system - real time data (1min)', 'Measured transmission of electricity in Finland from north to south (60min)', 'Net import/export of electricity - real time data (3min)', 'Nuclear power production - real time data (3min)', 'Ordered down-regulations from Balancing energy market in Finland (60min)', 'Ordered up-regulations from Balancing energy market in Finland (60min) ', 'Other power transactions down-regulation (60min)', 'Other power transactions up-regulation (60min)', 'Peak load power - real time data (3min)', 'Planned weekly capacity from north to south (60min)', 'Planned weekly capacity from south to north (60min)', 'Power system state - real time data (3min)', 'Price of the last activated up-regulation bid - real time data (60min)', 'Solar power generation forecast - updated hourly (60min)', 'Solar power generation forecast - updated once a day (24h)', 'Special regulation up-regulation (60min)', 'Special regulation down-regulation (60min)', 'Stock exchange capacity FI-RUS (60min)', 'Stock exchange trade FI-RUS-FI (60min)', 'Stock exchange capacity RUS-FI (60min)', 'Sum of aFRR energy bids up', 'Sum of aFRR energy bids down', 'Surplus/deficit cumulative - real time data (3min)', 'Temperature in Helsinki - real time data', 'Temperature in Jyväskylä - real time data (3min)', 'Temperature in Oulu - real time data (3min)', 'Temperature in Rovaniemi - real time data (3min)', 'The sum of the down-regulation bids in the Balancing energy market (60min)', 'The sum of the up-regulation bids in the balancing energy market (60min)', 'Time deviation - real time data (3min)', 'Total production capacity used in the solar power forecast (60min)', 'Total production capacity used in the wind power forecast (60min)', 'Transmission between Finland and Central Sweden - real time data (3min)', 'Transmission between Finland and Estonia - real time data (3min)', 'Transmission between Finland and Northern Sweden - real time data (3min)', 'Transmission between Finland and Norway - real time data (3min)', 'Transmission between Finland and Russia - real time data (3min)', 'Transmission between Finland and Åland - real time data (3min)', 'Transmission capacity FI-RUS (60min)', 'Transmission capacity RUS-FI (60min)', 'Transmission of electricity between Finland and Central Sweden - measured hourly data (60min)', 'Transmission of electricity between Finland and Estonia - measured hourly data (60min)', 'Transmission of electricity between Finland and Northern Sweden - measured hourly data (60min)', 'Transmission of electricity between Finland and Norway - measured hourly data (60min)', 'Transmission of electricity between Finland and Russia - measured hourly data (60min)', 'Transmission of electricity between Finland and Åland - measured hourly data (60min)', 'Up-regulating price in the Balancing energy market (60min)', 'Value of Avoided Activation (VoAA) DK1 (60min)', 'Value of Avoided Activation (VoAA) DK2 (60min)', 'Value of Avoided Activation (VoAA) FI (60min)', 'Value of Avoided Activation (VoAA) NO1 (60min)', 'Value of Avoided Activation (VoAA) NO2 (60min)', 'Value of Avoided Activation (VoAA) NO3 (60min)', 'Value of Avoided Activation (VoAA) NO4 (60min)', 'Value of Avoided Activation (VoAA) NO5 (60min)', 'Value of Avoided Activation (VoAA) SE1 (60min)', 'Value of Avoided Activation (VoAA) SE3 (60min)', 'Value of Avoided Activation (VoAA) SE4 (60min)', 'Wind power generation forecast - updated hourly (60min)', 'Wind power generation forecast - updated once a day (24h)', 'Wind power generation - hourly data (60min)', 'Wind power generation real time data (3min)'};
            app.SelectDropDown.Value = 'aFRR energy activation volume local selection down';
            app.DateDropDownLabel = uilabel(app.SearchTab);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.SearchTab);
            app.DateDropDown.Items = {'---', 'Current Week', 'Last Week', 'Current Month', 'Last Month'};
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.DateDropDown.Value = 'Current Week';
            app.unitsEditFieldLabel = uilabel(app.SearchTab);
            app.unitsEditFieldLabel.Text = 'units';
            app.unitsEditField = uieditfield(app.SearchTab, 'text');
            app.minEditFieldLabel = uilabel(app.SearchTab);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.SearchTab, 'numeric');
            app.maxEditFieldLabel = uilabel(app.SearchTab);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.SearchTab, 'numeric');
            app.avgEditFieldLabel = uilabel(app.SearchTab);
            app.avgEditFieldLabel.Text = 'avg';
            app.avgEditField = uieditfield(app.SearchTab, 'numeric');
            app.medEditFieldLabel = uilabel(app.SearchTab);
            app.medEditFieldLabel.Text = 'med';
            app.medEditField = uieditfield(app.SearchTab, 'numeric');
            app.stdEditFieldLabel = uilabel(app.SearchTab);
            app.stdEditFieldLabel.Text = 'std';
            app.stdEditField = uieditfield(app.SearchTab, 'numeric');
            app.varEditFieldLabel = uilabel(app.SearchTab);
            app.varEditFieldLabel.Text = 'var';
            app.varEditField = uieditfield(app.SearchTab, 'numeric');
            app.DescriptionTextAreaLabel = uilabel(app.SearchTab);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.SearchTab);
            app.DatePickerLabel = uilabel(app.SearchTab);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.SearchTab);
            app.DatePicker.Limits = [datetime([2015 1 1]) datetime([9999 12 31])];
            app.DatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DaterangeisonemonthfromtheselecteddateLabel = uilabel(app.SearchTab);
            app.DaterangeisonemonthfromtheselecteddateLabel.Text = {'Date range is one month from the selected date due'; 'to restrictions in the API accessibility. Data available 2015-.'};
            app.Image_2 = uiimage(app.SearchTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'power_flow_logo.png');
            app.ImportExportTab = uitab(app.TabGroup);
            app.ImportExportTab.Title = 'Import-Export';
            app.UIAxesIE = uiaxes(app.ImportExportTab);
            xlabel(app.UIAxesIE, 'Time')
            ylabel(app.UIAxesIE, 'MW')
            zlabel(app.UIAxesIE, 'Z')
            app.HTML = uihtml(app.ImportExportTab);
            app.HTML.HTMLSource = 'import_export_map.html';
            app.DateDropDown_2Label_2 = uilabel(app.ImportExportTab);
            app.DateDropDown_2Label_2.Text = 'Date';
            app.DateDropDownIE = uidropdown(app.ImportExportTab);
            app.DateDropDownIE.Items = {'Current Week', 'Last Week', 'Current Month', 'Last Month'};
            app.DateDropDownIE.Value = 'Current Week';
            app.GetDataButtonIE = uibutton(app.ImportExportTab, 'push');
            app.GetDataButtonIE.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonIEPushed, true);
            app.GetDataButtonIE.Text = 'Get Data';
            app.ExportButtonIE = uibutton(app.ImportExportTab, 'push');
            app.ExportButtonIE.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonIEPushed, true);
            app.ExportButtonIE.Text = 'Export';
            app.LabelExportIE = uilabel(app.ImportExportTab);
            app.LabelExportIE.Text = '';
            app.AvgEditFieldLabel = uilabel(app.ImportExportTab);
            app.AvgEditFieldLabel.Text = 'Avg';
            app.AvgEditFieldIE = uieditfield(app.ImportExportTab, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.ImportExportTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditFieldIE = uieditfield(app.ImportExportTab, 'numeric');
            app.MinEditFieldLabel = uilabel(app.ImportExportTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditFieldIE = uieditfield(app.ImportExportTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fingrid_api_app_auth

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end